classdef DialogBoxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EditField                     matlab.ui.control.EditField
        CalculateButton               matlab.ui.control.Button
        PositiveCoefficientEditField  matlab.ui.control.NumericEditField
        PositiveCoefficientLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainAppCaller % handle to Main caller app
        maxV %Max Value
    end
    
    %Helper Functions
    methods (Access = private)
        %Function to estimate if the insert coefficient value is valid
        function result=isValid(app,maxvalue,coeff)
            if coeff>maxvalue
                %Alert message
                msg=strcat("Please be aware that the provided value is too large: ", num2str(coeff));
                title=strcat("Coefficient greater than ", num2str(maxvalue));
                uialert(app.UIFigure,msg,title,'Icon','warning');
                % In case the coefficient is larger than the maximum value,
                % the function returns false, and a new coefficient needs 
                % to be added
                result=false;
            else
                %The coefficient value is smaller than the maximum value,
                %the result of the function is set to true
                result=true;
            end
        end   
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, maxvalue)
            %From the main app
            app.MainAppCaller=caller;
            app.maxV=maxvalue;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %Check if the insert coefficient is valid
            if isValid(app,app.maxV,app.PositiveCoefficientEditField.Value)
                %Call of the ParameterEstimator function in the Main App
                ParameterEstimator(app.MainAppCaller, app.PositiveCoefficientEditField.Value);
                % Delete the Dialog Box
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PositiveCoefficientLabel = uilabel(app.UIFigure);
            app.PositiveCoefficientLabel.Text = {'Positive'; 'Coefficient'};
            app.PositiveCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.PositiveCoefficientEditField.UpperLimitInclusive = 'off';
            app.PositiveCoefficientEditField.Limits = [0 Inf];
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Dialog Box';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogBoxApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end